classdef basicApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UITable                    matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        TimeAmplitudeButtonGroup   matlab.ui.container.ButtonGroup
        OneMin                     matlab.ui.control.RadioButton
        ThirtyMin                  matlab.ui.control.RadioButton
        OneHour                    matlab.ui.control.RadioButton
        SinceStart                 matlab.ui.control.RadioButton
        TemperatureEditFieldLabel  matlab.ui.control.Label
        TemperatureEditField       matlab.ui.control.NumericEditField
        HumidityEditFieldLabel     matlab.ui.control.Label
        HumidityEditField          matlab.ui.control.NumericEditField
        COMPortEditField           matlab.ui.control.EditField
        COMPortLabel               matlab.ui.control.Label
        LogPanel                   matlab.ui.container.Panel
        BrowseButton               matlab.ui.control.Button
        LogPathEditField           matlab.ui.control.EditField
        LogFileNameEditField       matlab.ui.control.EditField
        FilenameLabel              matlab.ui.control.Label
        OffsetButton               matlab.ui.control.Button
        STARTRESETButton           matlab.ui.control.Button
        STOPButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        interval = 60;
        workFlag = 0;
        tableSize = 8;
        logflag = 0;
        rf;
        fid;
        %offset = {0,0,0,0,0,0,0};
        values;
        i =0;
    end
    

    methods (Access = private)

        % Button pushed function: STARTRESETButton
        function STARTRESETButtonPushed(app, event)
            app.workFlag = 1;
            if(app.workFlag)
                %CHECK COMPORT WAS CHOOSED
                S = app.COMPortEditField.Value;
                if isempty(S)
                    f = uifigure;
                    uialert(f, 'COM Port Not Specified', 'Error')
                else
                    %CHECK IF COMPORT OPENNED
                    app.rf = fopen(S);
                    if (app.rf < 3)
                        f = uifigure;
                        uialert(f, 'COM Port Incorrect', 'Error');
                    else
                        %CHECK IF LOG PATH AND LOG FILE NAME ARE AVAILABLE
                        SS = app.LogPathEditField.Value;
                        SSS = app.LogFileNameEditField.Value;
                        if (isempty(SS) | isempty(SSS))
                            f = uifigure;
                            uialert(f, 'Data will not be logged', 'Warning');
                            app.logflag = 0;
                        else
                            filename = [SS '/' SSS];
                            app.fid = fopen(filename, 'wt');
                            if (app.fid < 3)
                                f = uifigure;
                                uialert(f, 'Log File Creation Failed. Data will not be logged', 'Warning');
                            else
                                app.logflag = 1;
                            end
                        end


                        %DO THE STUFF
                        line = fgetl(app.rf);                 %get data from com port
                        
                        app.values = zeros(1, 9);             %start data vector with 9 columns
                        app.i = 1;                            %start data vector index
                        while(line > 0 & app.workFlag)
                            
                            %%GET app.values FROM COM LINE
                            C = strsplit(line);
                            %dest = 1;
                            for a=1:9
                                app.values(app.i, a) = str2num(char(C(a*3)));     %get useful app.values from com port
                            end
                            
                            %%UPDATE HUMIDITY AND TEMPERATURE BOXES
                            app.HumidityEditField.Value = app.values(app.i, 8);
                            app.TemperatureEditField.Value = app.values(app.i, 9);
                            
                            %%PREPARE MOVING PLOT WINDOW
                            finalpoint = length(app.values(:,1));             %upper limit follows vector size
                            if(app.interval > 0)
                                newoffset = min(app.i, app.interval)-1;       %window grow in realtime before start moving
                            else
                                newoffset = finalpoint - 1;
                            end
                            startpoint = finalpoint - newoffset - 1;      %calculate starting point
                            t = 1:finalpoint;
                            
                            %hold(app.UIAxes);
                            plot(app.UIAxes, t, app.values(:,1), 'r', t, app.values(:,2), 'b', t, app.values(:,3), 'g', t, app.values(:,4), 'y', t, app.values(:,5), 'r', t, app.values(:,6), 'b', t, app.values(:,7), 'g' );
                            drawnow;
                            app.UIAxes.XLim = [startpoint, finalpoint];    %make window 'move'
                            
                            
                            %%UPDATE TABLE - THIS TAKES SO MUCH TIME!!!
                            actualTableSize = min(app.i, app.tableSize);
                            if (actualTableSize > 1)
                                for s = 0:actualTableSize-2
                                    %new_s = actualTableSize + 2 - s;
                                    app.UITable.Data(actualTableSize-s, :) = app.UITable.Data(actualTableSize-s-1, :);
                                end
                            end
                            app.UITable.Data(1, :) = app.values(app.i,1:7);
                            
                            %DO BACKUP HERE
                            if (app.logflag)
                                fprintf(app.fid, strcat(num2str(app.values(app.i,1)), '\t', num2str(app.values(app.i,2)), '\t', num2str(app.values(app.i,3)), '\t', num2str(app.values(app.i,4)), '\t',num2str(app.values(app.i,5)), '\t', num2str(app.values(app.i,6)), '\t', num2str(app.values(app.i,7)), '\t',num2str(app.values(app.i,8)), '\t',num2str(app.values(app.i,9)), '\n'));
                            end
                            
                            if (app.workFlag)
                                line = fgetl(app.rf);                %get next line from com port
                            else
                                line = -1;
                            end
                            app.i = app.i + 1;
                            
                            pause(1);
                        end
                    end
                end
            end
        end

        % Selection changed function: TimeAmplitudeButtonGroup
        function TimeAmplitudeButtonGroupSelectionChanged(app, event)
            if (app.OneMin.Value)
                app.interval = 60;
            end
            if (app.ThirtyMin.Value)
                app.interval = 1800;
            end
            if (app.OneHour.Value)
                app.interval = 3600;
            end
            if (app.SinceStart.Value)
                app.interval = -1;
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.workFlag = 0;
            app.logflag = 0;
            fclose(app.rf);
            if (app.fid > 2)
                fclose(app.fid);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.LogPathEditField.Value = uigetdir;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, false)
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'S1'; 'S2'; 'S3'; 'S4'; 'S5'; 'S6'; 'S7'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false false false false false true];
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time / s');
            ylabel(app.UIAxes, 'Displacement / mm');
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XLimMode = 'manual';
            app.TimeAmplitudeButtonGroup = uibuttongroup(app.UIFigure);
            app.TimeAmplitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimeAmplitudeButtonGroupSelectionChanged, true);
            app.TimeAmplitudeButtonGroup.Title = 'Time Amplitude';
            app.OneMin = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.OneMin.Text = '1 Minute';
            app.OneMin.Value = true;
            app.ThirtyMin = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.ThirtyMin.Text = '30 Minutes';
            app.OneHour = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.OneHour.Text = '1 Hour';
            app.SinceStart = uiradiobutton(app.TimeAmplitudeButtonGroup);
            app.SinceStart.Text = 'Since Start';
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField.ValueDisplayFormat = '%11.6g';
            app.TemperatureEditField.Editable = 'off';
            app.HumidityEditFieldLabel = uilabel(app.UIFigure);
            app.HumidityEditFieldLabel.Text = 'Humidity';
            app.HumidityEditField = uieditfield(app.UIFigure, 'numeric');
            app.HumidityEditField.ValueDisplayFormat = '%11.6g';
            app.HumidityEditField.Editable = 'off';
            app.COMPortEditField = uieditfield(app.UIFigure, 'text');
            app.COMPortEditField.Value = 'COM1';
            app.COMPortLabel = uilabel(app.UIFigure);
            app.COMPortLabel.Text = 'COM Port';
            app.LogPanel = uipanel(app.UIFigure);
            app.LogPanel.Title = 'Log';
            app.BrowseButton = uibutton(app.LogPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LogPathEditField = uieditfield(app.LogPanel, 'text');
            app.LogFileNameEditField = uieditfield(app.LogPanel, 'text');
            app.LogFileNameEditField.Value = 'LogFile.txt';
            app.FilenameLabel = uilabel(app.LogPanel);
            app.FilenameLabel.Text = 'Filename';
            app.OffsetButton = uibutton(app.UIFigure, 'push');
            app.OffsetButton.Text = 'Offset';
            app.STARTRESETButton = uibutton(app.UIFigure, 'push');
            app.STARTRESETButton.ButtonPushedFcn = createCallbackFcn(app, @STARTRESETButtonPushed, true);
            app.STARTRESETButton.Text = 'START/RESET';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
        end
    end

    methods (Access = public)

        % Construct app
        function app = basicApp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end